classdef adjust_multicolor_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        RedSliderLabel            matlab.ui.control.Label
        RedSlider                 matlab.ui.control.Slider
        GreenSliderLabel          matlab.ui.control.Label
        GreenSlider               matlab.ui.control.Slider
        BlueSliderLabel           matlab.ui.control.Label
        BlueSlider                matlab.ui.control.Slider
        LoadButton                matlab.ui.control.Button
        SelectOutliersButton      matlab.ui.control.Button
        RotateLabel               matlab.ui.control.Label
        ResetSlidersButton        matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        GammaGreenSlider          matlab.ui.control.Slider
        GammaBlueSlider           matlab.ui.control.Slider
        GammaRedSlider            matlab.ui.control.Slider
        RotationKnob              matlab.ui.control.Knob
        XButton                   matlab.ui.control.StateButton
        YButton                   matlab.ui.control.StateButton
        WeightLabel               matlab.ui.control.Label
        GammaLabel                matlab.ui.control.Label
        CropButton                matlab.ui.control.Button
        FlipLabel                 matlab.ui.control.Label
        TitleLabel                matlab.ui.control.Label
        SubtractBackgroundButton  matlab.ui.control.Button
        ClearOutliersButton       matlab.ui.control.Button
        AllGammaSlider            matlab.ui.control.Slider
        ChangeAllGammaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        path;
        full_file;
        num_channels;
        zero_mask;
        crop_mask;
        original_volume;
        original_max_projection;
        adjusted_image;
        adjusted_volume;
        background_mask;
        rotation_value;
        base_image;
        weighted_image;
        
        color_gamma = [0.4; 0.8; 0.4];
        color_weights = [0.5; 0.5; 0.5];
        flip_x = false;
        flip_y = false;
        
        rgb_indicies = [5, 3, 1];
    end
    
    methods (Access = private)
        function max_val = max_of_channel(~, image_in, num_from_max)
            % max_of_channel  returns the max value in each channel of an
            % image
            if nargin < 3
                num_from_max = 100;
            end
            
            image_size = size(image_in);
            
            switch length(size(image_in))
                case 3
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2), image_size(3)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 2]);
                case 4
                    columnized_image = reshape(image_in, [image_size(1)*image_size(2)*image_size(3), image_size(4)]);
                    sorted_image = sort(columnized_image, 1, 'descend');
                    max_val = permute(mean(sorted_image(1:num_from_max, :)), [1, 3, 4, 2]);
            end
            
            max_val(max_val == 0) = 1;
        end
        
        function construct_image(app, use_volume, apply_gamma)
            % construct_image  applies each adjustment to a volume in order
            % then generates a 2d image to display
            % adjustments are applied in order:
            % Zero masking
            % Normalizing
            % Weighting each color channel
            % Flipping image in xyz
            % Rotate image around z axis
            % Roll image around x axis
            
            if nargin < 2
                use_volume = false;
            end
            
            if nargin < 3
                apply_gamma = true;
            end
            
            if use_volume
                image_in = app.original_volume;
            else
                image_in = app.original_max_projection;
            end

            %% perform background subtraction
            background_value = zeros(1, 1, 1, app.num_channels);
            
            if any(app.background_mask(:))
                for cc = 1:app.num_channels
                    for ss = 1:size(image_in, 3)
                        this_frame = image_in(:, :, ss, cc);
                        background_value(cc) = background_value(cc) + sum(this_frame(app.background_mask), 'all');
                    end
                end
                
                background_value = background_value / sum(app.background_mask, 'all') / size(image_in, 3);
            end
            
            %% zero_mask image
            zero_masked_image = (image_in  - background_value) .* app.zero_mask;
            
            cropped_image = zero_masked_image(app.crop_mask(1, 1):app.crop_mask(1,2), app.crop_mask(2,1):app.crop_mask(2,2), :, :);
            
            %% normalize image
            max_val = app.max_of_channel(cropped_image);
            normalized_zero_masked_image = cropped_image ./ max_val;
            normalized_zero_masked_image(normalized_zero_masked_image > 1) = 1;
            normalized_zero_masked_image(normalized_zero_masked_image < 0) = 0;
                        
            if apply_gamma
                %% gamma image
                gamma_image = zeros(size(normalized_zero_masked_image));
                gamma_in = ones(app.num_channels, 1) / 2;
                gamma_in(app.rgb_indicies) = app.color_gamma;
                
                for ss = 1:size(normalized_zero_masked_image, 3)
                    for ff = 1:app.num_channels
                        gamma_image(:, :, ss, ff) = imadjust(normalized_zero_masked_image(:, :, ss, ff), [], [], gamma_in(ff));
                    end
                end
            else
                gamma_image = normalized_zero_masked_image;
            end
            
            %% weight image
            max_weights = max([max(app.color_weights) 0.05]);
            
            weights = ones(1, 1, 1, app.num_channels) * max(app.color_weights);
            weights(app.rgb_indicies) = app.color_weights;
            
            app.weighted_image = gamma_image .* weights ./ max_weights;
            
            flipped_image = app.weighted_image;
            
            %% flip_image
            if app.XButton.Value
                flipped_image = flip(flipped_image, 2);
            end
            
            if app.YButton.Value
                flipped_image = flip(flipped_image, 1);
            end
            
            % flip z axis if either x or y has been flipped (but not both)
            if xor(app.XButton.Value, app.YButton.Value)
                flipped_image = flip(flipped_image, 3);
            end
            
            %% rotate image
            app.adjusted_volume = imrotate(flipped_image, -app.rotation_value, 'bilinear');
            
            app.adjusted_image = app.get_max_proj(app.adjusted_volume);
        end
        
        function plot_zstack(app)
            app.construct_image();
            
            imagesc(app.UIAxes, app.adjusted_image);
        end
        
        function max_proj = get_max_proj(app, volume)
            max_proj = squeeze(max(volume(:, :, :, app.rgb_indicies), [], 3));
        end
        
        function reset_sliders(app)
            % reset weights
            red_weight = app.full_file.weights(app.rgb_indicies(1));
            green_weight = app.full_file.weights(app.rgb_indicies(2));
            blue_weight = app.full_file.weights(app.rgb_indicies(3));
            
            app.RedSlider.Value = red_weight;
            app.GreenSlider.Value = green_weight;
            app.BlueSlider.Value = blue_weight;
            
            app.color_weights = [red_weight, green_weight, blue_weight];
            
            % reset gamma
            red_gamma = app.full_file.gamma_val(app.rgb_indicies(1));
            green_gamma = app.full_file.gamma_val(app.rgb_indicies(2));
            blue_gamma = app.full_file.gamma_val(app.rgb_indicies(3));
            
            app.GammaRedSlider.Value = red_gamma;
            app.GammaGreenSlider.Value = green_gamma;
            app.GammaBlueSlider.Value = blue_gamma;
            
            app.color_gamma = [red_gamma, green_gamma, blue_gamma];
            
            % reset flip
            app.XButton.Value = app.full_file.flipX;
            app.YButton.Value = app.full_file.flipY;
            
            % reset rotation
            app.RotationKnob.Value = app.full_file.rotation;
            
            app.plot_zstack();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.Text = "LOADING...";
            
            config = get_config();
            
            [file, path_in] = uigetfile([config.data_location '/*multicolor_adjustment.mat'], 'Select multicolor_adjustment file in multicolor folder');
            
            if all(file == 0)
                return;
            end
            
            app.path = fullfile(path_in, file);
            app.full_file = load(app.path, '-mat');
            
            [date_folder_path, enclosing_folder] = fileparts(path_in(1:end-1));
            [~, date_folder] = fileparts(date_folder_path);
            file_name = fullfile(date_folder, enclosing_folder);
            
            % set the title to reflect the file you loaded
            app.TitleLabel.Text = file_name;
            
            % grab multicolor data
            app.original_volume = app.full_file.original_data;
            app.original_max_projection = max(app.original_volume, [], 3);
            app.zero_mask = app.full_file.zero_mask;
            app.crop_mask = app.full_file.crop_mask;
            
            app.num_channels = size(app.original_volume, 4);
            
            app.base_image = app.original_max_projection ./ app.max_of_channel(app.original_max_projection);
            app.base_image = app.base_image(:, :, app.rgb_indicies);
            
            % set the background region
            if isfield(app.full_file, 'background_mask')
                app.background_mask = app.full_file.background_mask;
            else
                app.background_mask = false;
            end
            
            app.rotation_value = app.full_file.rotation;
            
            app.reset_sliders();
            app.LoadButton.Text = "Load";
        end

        % Button pushed function: SelectOutliersButton
        function SelectOutliersButtonPushed(app, event)
            % make zero_mask. This mask will be used to set all values
            % in mask to 0
            app.construct_image();
            
            non_rgb_indicies = 1:5;
            non_rgb_indicies(app.rgb_indicies) = [];
            display_image = app.weighted_image(:, :, app.rgb_indicies) + sum(app.weighted_image(:, :, non_rgb_indicies), 3);
            display_image = display_image ./ max(max(display_image, [], 1), [], 2);
            
            h = figure;
            imagesc(display_image);
            axis('equal');
            roi = drawpolygon;
            
            if isvalid(roi)
                app.zero_mask(app.crop_mask(1, 1):app.crop_mask(1,2), app.crop_mask(2,1):app.crop_mask(2,2)) = app.zero_mask(app.crop_mask(1, 1):app.crop_mask(1,2), app.crop_mask(2,1):app.crop_mask(2,2)) & ~roi.createMask;
                close(h);
            end
            
            app.plot_zstack();
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            app.reset_sliders();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'SAVING...';
            pause(0.1);
            
            % save all the modification values so that if the file is
            % loaded again the user starts where they left off.
            weights = ones(app.num_channels, 1);
            weights(app.rgb_indicies) = app.color_weights;
            
            gamma_val = ones(app.num_channels, 1) / 2;
            gamma_val(app.rgb_indicies) = app.color_gamma;
            
            adjustment_to_save = app.full_file;
            adjustment_to_save.gamma_val = gamma_val;
            adjustment_to_save.zero_mask = app.zero_mask;
            adjustment_to_save.weights = weights;
            adjustment_to_save.flipX = app.XButton.Value;
            adjustment_to_save.flipY = app.YButton.Value;
            adjustment_to_save.rotation = app.RotationKnob.Value;
            adjustment_to_save.crop_mask = app.crop_mask;
            adjustment_to_save.background_mask = app.background_mask;
            
            % we used to construct the image without gamma so that gamma
            % could be applied in visualize_light. However, since
            % background subtraction happens afterwards, it broke this path
            % until I add it into visualize light. for now do all
            % processing here and set downstream gamma to 1
            app.construct_image(true, false);
            adjustment_to_save.data = app.adjusted_volume;
            
            struct_h = whos('adjustment_to_save');
            bytes_in_struct = struct_h.bytes;
            if bytes_in_struct < 2e9
                save(app.path, '-struct', 'adjustment_to_save');
            else
                disp('struct > 2 GB, saving in -v7.3');
                save(app.path, '-struct', 'adjustment_to_save', '-v7.3');
            end
            
            %% save .mat file
            file_folder = fileparts(app.path);
            file_path = fullfile(file_folder, 'neuropal_data.mat');
            
            data_as_int = uint16(app.adjusted_volume*(2^16-1));
            neuropal_data = get_default_neuropal_input(data_as_int, file_path);
            neuropal_data.info.scale = app.full_file.scale;
            
            neuropal_data.info.gamma = app.color_gamma;
            neuropal_data.prefs.gamma = app.color_gamma;
            
            struct_h = whos('neuropal_data');
            bytes_in_struct = struct_h.bytes;
            if bytes_in_struct < 2e9
                save(file_path, '-struct', 'neuropal_data');
            else
                disp('struct > 2 GB, saving in -v7.3');
                save(file_path, '-struct', 'neuropal_data', '-v7.3');
            end
            
            % used to delete old ID files, for now we will keep them. If
            % the user wants to get new cell_IDs they'll rerun it
%             old_file_path = fullfile(file_folder, 'neuropal_data_ID.mat');
%             if exist(old_file_path, 'file')
%                 delete(old_file_path);
%             end
            
            % old neuropal trimmed neuropal files
            old_file_path = fullfile(file_folder, 'neuropal_data_trimmed.mat');
            if exist(old_file_path, 'file')
                delete(old_file_path);
            end
            
            % delete old trimmed ID file
            old_file_path = fullfile(file_folder, 'neuropal_data_trimmed_ID.mat');
            if exist(old_file_path, 'file')
                delete(old_file_path);
            end
            
            % check if a calcium to multicolor alignment exists
            % if it does, create a trimmed multicolor file
            panneuronal_folder = fileparts(fileparts(app.path(1:end-1)));
            multicolor_alignment_path = fullfile(panneuronal_folder, 'calcium_to_multicolor_alignment.mat');
            
            if exist(multicolor_alignment_path, 'file')
                create_trimmed_multicolor_file(panneuronal_folder);
            end
            
            disp(['Saved ' app.TitleLabel.Text]);
            
            app.SaveButton.Text = 'Save';
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.plot_zstack();
        end

        % Callback function
        function RollKnobValueChanged(app, event)
            app.plot_zstack();
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            h = figure;
            imagesc(app.base_image);
            axis('equal');
            roi = drawrectangle;
            
            if isvalid(roi)
                binary_crop_mask = roi.createMask;
                close(h);
            
                first = find(binary_crop_mask, 1, 'first');
                last = find(binary_crop_mask, 1, 'last');
                
                [app.crop_mask(1, 1), app.crop_mask(2, 1)] = ind2sub(size(binary_crop_mask), first);
                [app.crop_mask(1, 2), app.crop_mask(2, 2)] = ind2sub(size(binary_crop_mask), last);
            end
            
            app.plot_zstack();
        end

        % Button pushed function: SubtractBackgroundButton
        function SubtractBackgroundButtonPushed(app, event)
            h = figure;
            imagesc(app.base_image);
            title('select an empty region');
            axis('equal');
            roi = drawpolygon;
            
            if isvalid(roi)
                app.background_mask = roi.createMask;
                close(h);
            end
            
            app.plot_zstack();
        end

        % Value changing function: RotationKnob
        function RotationKnobValueChanging(app, event)
            app.rotation_value = event.Value;
            app.plot_zstack();
        end

        % Button pushed function: ClearOutliersButton
        function ClearOutliersButtonPushed(app, event)
            app.zero_mask = ones(size(app.zero_mask));
            app.plot_zstack();
        end

        % Value changing function: RedSlider
        function RedSliderValueChanging(app, event)
            app.color_weights(1) = event.Value;
            app.plot_zstack();
        end

        % Value changing function: GreenSlider
        function GreenSliderValueChanging(app, event)
            app.color_weights(2) = event.Value;
            app.plot_zstack();
        end

        % Value changing function: BlueSlider
        function BlueSliderValueChanging(app, event)
            app.color_weights(3) = event.Value;
            app.plot_zstack();
        end

        % Value changing function: GammaRedSlider
        function GammaRedSliderValueChanging(app, event)
            app.color_gamma(1) = event.Value;
            app.plot_zstack();
        end

        % Value changing function: GammaGreenSlider
        function GammaGreenSliderValueChanging(app, event)
            app.color_gamma(2) = event.Value;
            app.plot_zstack();
        end

        % Value changing function: GammaBlueSlider
        function GammaBlueSliderValueChanging(app, event)
            app.color_gamma(3) = event.Value;
            app.plot_zstack();
        end

        % Value changed function: AllGammaSlider
        function AllGammaSliderValueChanged(app, event)
            gamma_add_val = app.AllGammaSlider.Value;
            max_slider = max(app.color_gamma);
            min_slider = min(app.color_gamma);
            max_allowed = app.GammaGreenSlider.Limits(2) - max_slider;
            min_allowed = app.GammaGreenSlider.Limits(1) - min_slider;
            gamma_add_val = min([gamma_add_val, max_allowed]);
            gamma_add_val = max([gamma_add_val, min_allowed]);
            
            app.color_gamma(1) = app.color_gamma(1) + gamma_add_val;
            app.color_gamma(2) = app.color_gamma(2) + gamma_add_val;
            app.color_gamma(3) = app.color_gamma(3) + gamma_add_val;
            
            app.GammaRedSlider.Value = app.color_gamma(1);
            app.GammaGreenSlider.Value = app.color_gamma(2);
            app.GammaBlueSlider.Value = app.color_gamma(3);
            
            app.AllGammaSlider.Value = 0;
            
            app.plot_zstack();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Limits = [0 1];
            app.RedSlider.ValueChangingFcn = createCallbackFcn(app, @RedSliderValueChanging, true);
            app.RedSlider.Value = 0.5;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Limits = [0 1];
            app.GreenSlider.ValueChangingFcn = createCallbackFcn(app, @GreenSliderValueChanging, true);
            app.GreenSlider.Value = 0.5;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Limits = [0 1];
            app.BlueSlider.ValueChangingFcn = createCallbackFcn(app, @BlueSliderValueChanging, true);
            app.BlueSlider.Value = 0.5;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectOutliersButton = uibutton(app.UIFigure, 'push');
            app.SelectOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutliersButtonPushed, true);
            app.SelectOutliersButton.Text = 'Select Outliers';
            app.RotateLabel = uilabel(app.UIFigure);
            app.RotateLabel.Text = 'Rotate';
            app.ResetSlidersButton = uibutton(app.UIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GammaGreenSlider = uislider(app.UIFigure);
            app.GammaGreenSlider.Limits = [0 1.25];
            app.GammaGreenSlider.ValueChangingFcn = createCallbackFcn(app, @GammaGreenSliderValueChanging, true);
            app.GammaGreenSlider.Value = 0.8;
            app.GammaBlueSlider = uislider(app.UIFigure);
            app.GammaBlueSlider.Limits = [0 1.25];
            app.GammaBlueSlider.ValueChangingFcn = createCallbackFcn(app, @GammaBlueSliderValueChanging, true);
            app.GammaBlueSlider.Value = 0.4;
            app.GammaRedSlider = uislider(app.UIFigure);
            app.GammaRedSlider.Limits = [0 1.25];
            app.GammaRedSlider.ValueChangingFcn = createCallbackFcn(app, @GammaRedSliderValueChanging, true);
            app.GammaRedSlider.Value = 0.4;
            app.RotationKnob = uiknob(app.UIFigure, 'continuous');
            app.RotationKnob.Limits = [-180 180];
            app.RotationKnob.ValueChangingFcn = createCallbackFcn(app, @RotationKnobValueChanging, true);
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y';
            app.WeightLabel = uilabel(app.UIFigure);
            app.WeightLabel.Text = 'Weight';
            app.GammaLabel = uilabel(app.UIFigure);
            app.GammaLabel.Text = 'Gamma';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Data path';
            app.SubtractBackgroundButton = uibutton(app.UIFigure, 'push');
            app.SubtractBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractBackgroundButtonPushed, true);
            app.SubtractBackgroundButton.Text = 'Subtract Background';
            app.ClearOutliersButton = uibutton(app.UIFigure, 'push');
            app.ClearOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearOutliersButtonPushed, true);
            app.ClearOutliersButton.Text = 'Clear Outliers';
            app.AllGammaSlider = uislider(app.UIFigure);
            app.AllGammaSlider.Limits = [-0.5 0.5];
            app.AllGammaSlider.ValueChangedFcn = createCallbackFcn(app, @AllGammaSliderValueChanged, true);
            app.ChangeAllGammaLabel = uilabel(app.UIFigure);
            app.ChangeAllGammaLabel.Text = {'change all'; 'gamma'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjust_multicolor_image

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end