classdef assign_calcium_to_multicolor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        FramesToAverageLabel         matlab.ui.control.Label
        FramesToAverageSpinner       matlab.ui.control.Spinner
        RotateKnobLabel              matlab.ui.control.Label
        RotateKnob                   matlab.ui.control.Knob
        XFlipButton                  matlab.ui.control.StateButton
        YFlipButton                  matlab.ui.control.StateButton
        FlipLabel                    matlab.ui.control.Label
        FrameSpinner                 matlab.ui.control.Spinner
        ImageSizeLabel               matlab.ui.control.Label
        ImageSizeWarningLabel        matlab.ui.control.Label
        ImageSizeOutputLabel         matlab.ui.control.Label
        NumCellsIdLabel              matlab.ui.control.Label
        NumCellsIdOutputLabel        matlab.ui.control.Label
        CalciumZDepthSliderLabel     matlab.ui.control.Label
        CalciumZDepthSlider          matlab.ui.control.Slider
        MulticolorZDepthSliderLabel  matlab.ui.control.Label
        MulticolorZDepthSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        config = get_config();
        data_folder;
        reference_average;
        stack_ind;
        stack_size;
        shift_val;
        hi_res_data;
        assignment = [];
        rotation = 0;
        flip_x = false;
        flip_y = false;
        calcium_z;
        multicolor_z;
        multicolor_image;
        point_stats;
    end
    
    methods (Access = private)
        function display_images(app)
            % plot the calcium image
            flipped_image = squeeze(app.reference_average(:, :, app.calcium_z));
            
            if app.flip_x
                flipped_image = flip(flipped_image, 2);
            end
            
            if app.flip_y
                flipped_image = flip(flipped_image, 1);
            end
            
            if xor(app.flip_x, app.flip_y)
                flipped_image = flip(flipped_image, 3);
            end
            
            rotated_image = imrotate(flipped_image, -app.rotation);
            
            imagesc(app.UIAxes1, rotated_image);
            colormap(app.UIAxes1, gray);
            app.ImageSizeOutputLabel.Text = num2str(size(app.reference_average));
            
            % plot the multicolor image
            multicolor_image_out = squeeze(app.multicolor_image(:, :, app.multicolor_z, :));
            imagesc(app.UIAxes2, multicolor_image_out);
            colormap(app.UIAxes2, gray);
            
            if ~isempty(app.point_stats)
                num_cells = sum(~isnan(app.point_stats(app.stack_ind).trackIdx));
                app.NumCellsIdOutputLabel.Text = num2str(num_cells);
            else
                app.NumCellsIdOutputLabel.Text = 'unavailable';
            end
        end
        
        function average_frames(app)
            % create_calcium_reference  Average together the first n volumes of a
            % calcium recording together. We will use this average volume to get
            % initial cell body locations
            
            % choose which volumes to average over. Usually good to average around
            % 2s and start 100 volumes in to avoid issues at the beginning
            volumes_to_grab = app.stack_ind : app.stack_ind + app.stack_size - 1;
            
            % volumes are recorded top -> bottom then bottom -> top
            % we need to flip these to align, so search for the shift that alligns
            % the flipped stacks
            shift_search_range = 5;
            
            % average together the red (true) or green channel (false)
            use_red_reference = true;
            
            %% flip the volumes that are measured backwards
            % Volumes that are measured backward will be flipped

            % get volumes
            if use_red_reference
                [initial_volumes, ~, z_loc] = app.load_calcium_from_dat(app.data_folder, volumes_to_grab);
            else
                [~, initial_volumes, z_loc] = app.load_calcium_from_dat(app.data_folder, volumes_to_grab);
            end

            % find the whether the first from goes top to bottom or the
            % reverse
            norm_mod = double(z_loc(1, 1) > z_loc(end, 1));

            % calculate the average of the non flipped volumes
            norm_vol_ind = 2 - norm_mod;
            norm_vol = mean(initial_volumes(:, :, :, norm_vol_ind:2:end), 4);
            % calculate the std over z for the volume that isn't flipped
            norm_std = permute(std(norm_vol, [], [1, 2]), [3, 1, 2]);
            norm_std = norm_std - norm_std(1);

            % calculate the average of the flipped volumes
            flip_vol_ind = norm_mod + 1;
            flip_vol = mean(initial_volumes(:, :, :, flip_vol_ind:2:end), 4);
            % calculate the std over z for the volume that is flipped
            flip_std = permute(std(flip_vol, [], [1, 2]), [3, 1, 2]);
            flip_std = flip_std - flip_std(1);

            % find the correlation between the first two frames
            [~, shift_val_init] = max(xcorr(norm_std, flip(flip_std, 1), shift_search_range));
            app.shift_val = shift_val_init - shift_search_range - 1;

            % truncate the frames so they overlap correctly after flipping
            if app.shift_val >= 0
                frames_to_keep_final = (1 + app.shift_val) : size(initial_volumes, 3);
            else
                frames_to_keep_final = 1 : size(initial_volumes, 3) + app.shift_val;
            end

            reference_volumes = initial_volumes(:, :, frames_to_keep_final, :);

            % flip the stacks that are aquired backward
            for vv = flip_vol_ind:2:size(reference_volumes, 4)
                reference_volumes(:, :, :, vv) = flip(reference_volumes(:, :, :, vv), 3);
            end
            
            % average volumes together
            raw_average = mean(reference_volumes, 4);
            raw_zstack = max(raw_average, [], 3);
            
            % subtract the background
            background_fraction = 0.1;
            darkest_pixels = sort(raw_zstack(:), 'ascend');
            cutoff_ind = round(background_fraction * length(darkest_pixels));
            background_value = mean(darkest_pixels(1:cutoff_ind));
            
            app.reference_average = raw_average - background_value;
            
            app.CalciumZDepthSlider.Limits = [1, size(app.reference_average, 3)];
            app.CalciumZDepthSlider.Value = round(mean(app.CalciumZDepthSlider.Limits));
            app.calcium_z = app.CalciumZDepthSlider.Value;
        end
        
        function [rows,cols] = get_dat_dimensions(~, string)
            %getdatdimension takes a string produced by the whole brain imaging system,
            %which has the image dimensions in the text, and parses the text to extract
            %the rows and columns. string is in the format sCMOS_Frames_U16_1024x512.dat
            %  [rows,cols]=getdatdimensions(string)
        
            xloc= find(string=='x',1,'last');
            dotloc=find(string=='.',1,'last');
            numstart= find(string=='_',1,'last');
            rows=str2double(string(numstart+1:xloc-1));
            cols=str2double(string(xloc+1:dotloc-1));
        end
        
        function [red, green, z_loc] = load_calcium_from_dat(app, data_folder, volumes_to_grab)
            % load_calcium_from_dat  will get all the volumes_to_grab volumes from a
            % calcium recording. All of this code was ripped from the 3dbrain
            % pipeline https://github.com/leiferlab/3dbrain
            
            num_stacks = length(volumes_to_grab);
            
            % z location of the scope
            zWave=app.hi_res_data.Z;
            zWave=gradient(zWave);
            zWave=smooth(zWave,100);
            
            % standard deviation of the image over z
            image_std=app.hi_res_data.imSTD;
            image_std=image_std-mean(image_std);
            image_std(image_std>150)=0;
            [ZSTDcorrplot,lags]=crosscorr(abs(zWave),image_std,30);
            ZSTDcorrplot=smooth(ZSTDcorrplot,3);
            zOffset=lags(ZSTDcorrplot==max(ZSTDcorrplot));
        
            %get image path
            datFileDir=dir([data_folder filesep 'sCMOS_Frames_U16_*']);
            datFile=[data_folder filesep datFileDir.name];
            
            %get image size
            [rows,cols] = app.get_dat_dimensions(datFile);
            nPix=rows*cols;
            
            alignments=load([data_folder filesep 'alignments']);
            alignments=alignments.alignments;
            
            % location on the camera of the red (rect1) and green (rect2) image
            rect1 = alignments.S2AHiRes.rect1;
            rect2 = alignments.S2AHiRes.rect2;
            
            Fid=fopen(datFile);
            
            red_cell = cell(num_stacks, 1);
            green_cell = cell(num_stacks, 1);
            z_loc_cell = cell(num_stacks, 1);
            
            % grab the frames associated with each volume
            for ii = 1:num_stacks
                %select frames to analyze
                hiResIdx=find(app.hi_res_data.stackIdx==volumes_to_grab(ii))+ zOffset;
        
                %do something with status errors!
                status=fseek(Fid,2*(hiResIdx(1))*nPix,-1);
        
                pixelValues=fread(Fid,nPix*(length(hiResIdx)),'uint16',0,'l');
                hiResImage=reshape(pixelValues,rows,cols,length(hiResIdx));
                
                % put each volume into either a cell array
                red_cell{ii}=hiResImage((rect1(2)+1):rect1(4),(1+rect1(1)):rect1(3),:);
                green_cell{ii}=hiResImage((rect1(4)+1):end,(1+rect1(1)):rect1(3),:);
                z_loc_cell{ii} = app.hi_res_data.Z(hiResIdx);
            end
            
            % find the minimum of each dimension in the cell array, crop
            % each frame to the minimum and then turn into a matrix
            image_dim = cell2mat(cellfun(@size, [red_cell; green_cell], 'UniformOutput', false));
            min_dim = min(image_dim, [], 1);
            
            cropped_red = cellfun(@(x) x(1:min_dim(1), 1:min_dim(2), 1:min_dim(3)), red_cell, 'UniformOutput', false);
            cropped_green = cellfun(@(x) x(1:min_dim(1), 1:min_dim(2), 1:min_dim(3)), green_cell, 'UniformOutput', false);
            cropped_z = cellfun(@(x) x(1:min_dim(3)), z_loc_cell, 'UniformOutput', false);
            
            red = cell2mat(permute(cropped_red, [4, 2, 3, 1]));
            green = cell2mat(permute(cropped_green, [4, 2, 3, 1]));
            z_loc = cell2mat(cropped_z');
            
            fclose(Fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes1, 'equal');
            axis(app.UIAxes2, 'equal');
            app.stack_ind = app.FrameSlider.Value;
            app.stack_size = app.FramesToAverageSpinner.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.data_folder = uigetdir(app.config.data_location, 'Select the brainscanner folder');
    
            if all(app.data_folder == 0)
                return;
            end
                        
            % get the information from the high resolution microscope recording
            hiResData_path = fullfile(app.data_folder, 'hiResData.mat');
            app.hi_res_data = load(hiResData_path);
            app.hi_res_data = app.hi_res_data.dataAll;
            
            % load in PointStats2 which has all the cell body locations
            point_stats_path = fullfile(app.data_folder, 'PointsStats2.mat');
            
            if exist(point_stats_path,'file')
                point_stats_in = load(point_stats_path);
                app.point_stats = point_stats_in.pointStats2;
            end
            
            % load in the multicolor data
            multicolor_search = dir(fullfile(app.data_folder, 'multicolor*'));
            
            if isempty(multicolor_search)
                error('cannot find the multicolor folder');
            end
                        
            multicolor_search = multicolor_search(1); % take only the first result. Can't handle multiple multicolor folders in one file
            multicolor_folder = fullfile(multicolor_search.folder, multicolor_search.name);
            
            neuropal_file_path = fullfile(multicolor_folder, 'neuropal_data.mat');
            
            if exist(neuropal_file_path, 'file')
                multicolor_data = load(fullfile(multicolor_folder, 'neuropal_data.mat'));
                pan_ind = multicolor_data.info.RGBW(4);
                
                app.multicolor_image  = squeeze(multicolor_data.data(:, :, :, pan_ind));
                                
                app.MulticolorZDepthSlider.Limits = [1, size(app.multicolor_image, 3)];
                app.MulticolorZDepthSlider.Value = round(mean(app.MulticolorZDepthSlider.Limits));
                app.multicolor_z = app.MulticolorZDepthSlider.Value;
            else
                error('cannot find neuropal cell id file');
            end
            
            % check if an assignment already exists
            assignment_name = 'calcium_to_multicolor_assignment.mat';
            assignment_path = fullfile(app.data_folder, assignment_name);
            if exist(assignment_path, 'file')
                app.assignment = load(assignment_path);
            end
            
            % set default values
            app.RotateKnob.Value = 0;
            app.XFlipButton.Value = false;
            app.YFlipButton.Value = false;
            app.FramesToAverageSpinner.Value = 1;
            
            app.FrameSlider.Value = 100;
            app.FrameSlider.Limits = double([10, app.hi_res_data.stackIdx(end) - 10]);
            app.FrameSpinner.Value = app.FrameSlider.Value;
            app.FrameSpinner.Limits = double([10, app.hi_res_data.stackIdx(end) - 10]);

            app.average_frames();
            app.display_images();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.Text = 'PLEASE WAIT';
            pause(0.1);
            
            %% Convert to neuropal format
            save_path = fullfile(app.data_folder, 'calcium_data_average_stack.mat');

            % neuropal software requires a 4d matrix with the 4th dimension
            % representing the different color channels
            % just duplicate the tagRFP data
            rep_average = repmat(uint16(app.reference_average), [1, 1, 1, 5]);
            
            neuropal_input = get_default_neuropal_input(rep_average, save_path);
            neuropal_input.shift_val = app.shift_val;
            neuropal_input.stack_ind = app.stack_ind;
            neuropal_input.stack_size = app.stack_size;
            
            %% save the volume
            save(save_path, '-struct', 'neuropal_input');

            % remove the old neuropal file so that neuropal doesn't load it
            old_file_path = fullfile(app.data_folder, 'calcium_data_average_stack_ID.mat');

            if exist(old_file_path, 'file')
                delete(old_file_path);
            end
            
            app.SaveButton.Text = 'Save';
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            app.stack_ind = round(event.Value);
            app.FrameSpinner.Value = app.stack_ind;
            app.average_frames();
            app.display_images();
        end

        % Value changing function: RotateKnob
        function RotateKnobValueChanging(app, event)
            app.rotation = event.Value;
            app.display_images();
        end

        % Value changed function: XFlipButton
        function XFlipButtonValueChanged(app, event)
            app.flip_x = app.XFlipButton.Value;
            app.display_images();
        end

        % Value changed function: YFlipButton
        function YFlipButtonValueChanged(app, event)
            app.flip_y = app.YFlipButton.Value;
            app.display_images();
        end

        % Value changing function: FrameSpinner
        function FrameSpinnerValueChanging(app, event)
            new_frame_ind = event.Value;
            app.FrameSlider.Value = new_frame_ind;
            app.stack_ind = new_frame_ind;
            app.average_frames();
            app.display_images();
        end

        % Value changed function: FramesToAverageSpinner
        function FramesToAverageSpinnerValueChanged(app, event)
            app.stack_size = app.FramesToAverageSpinner.Value;
            app.average_frames();
            app.display_images();
        end

        % Value changing function: CalciumZDepthSlider
        function CalciumZDepthSliderValueChanging(app, event)
            app.calcium_z = round(event.Value);
            app.display_images();
        end

        % Value changing function: MulticolorZDepthSlider
        function MulticolorZDepthSliderValueChanging(app, event)
            app.multicolor_z = round(event.Value);
            app.display_images();
        end

        % Value changed function: CalciumZDepthSlider
        function CalciumZDepthSliderValueChanged(app, event)
            app.CalciumZDepthSlider.Value = round(app.CalciumZDepthSlider.Value);
        end

        % Value changed function: MulticolorZDepthSlider
        function MulticolorZDepthSliderValueChanged(app, event)
            app.MulticolorZDepthSlider.Value = round(app.MulticolorZDepthSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [10 1000];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 100;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FramesToAverageLabel = uilabel(app.UIFigure);
            app.FramesToAverageLabel.Text = 'Frames To Average';
            app.FramesToAverageSpinner = uispinner(app.UIFigure);
            app.FramesToAverageSpinner.Limits = [1 Inf];
            app.FramesToAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @FramesToAverageSpinnerValueChanged, true);
            app.FramesToAverageSpinner.Value = 1;
            app.RotateKnobLabel = uilabel(app.UIFigure);
            app.RotateKnobLabel.Text = 'Rotate';
            app.RotateKnob = uiknob(app.UIFigure, 'continuous');
            app.RotateKnob.Limits = [-180 180];
            app.RotateKnob.ValueChangingFcn = createCallbackFcn(app, @RotateKnobValueChanging, true);
            app.XFlipButton = uibutton(app.UIFigure, 'state');
            app.XFlipButton.ValueChangedFcn = createCallbackFcn(app, @XFlipButtonValueChanged, true);
            app.XFlipButton.Text = 'X';
            app.YFlipButton = uibutton(app.UIFigure, 'state');
            app.YFlipButton.ValueChangedFcn = createCallbackFcn(app, @YFlipButtonValueChanged, true);
            app.YFlipButton.Text = 'Y';
            app.FlipLabel = uilabel(app.UIFigure);
            app.FlipLabel.Text = 'Flip';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangingFcn = createCallbackFcn(app, @FrameSpinnerValueChanging, true);
            app.FrameSpinner.Limits = [10 1000];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.Value = 100;
            app.ImageSizeLabel = uilabel(app.UIFigure);
            app.ImageSizeLabel.Text = 'Image size is:';
            app.ImageSizeWarningLabel = uilabel(app.UIFigure);
            app.ImageSizeWarningLabel.Text = {'If this seems wrong,'; 'consider a different frame'};
            app.ImageSizeOutputLabel = uilabel(app.UIFigure);
            app.ImageSizeOutputLabel.Text = '<image size here>';
            app.NumCellsIdLabel = uilabel(app.UIFigure);
            app.NumCellsIdLabel.Text = '# cells ID''d in frame:';
            app.NumCellsIdOutputLabel = uilabel(app.UIFigure);
            app.NumCellsIdOutputLabel.Text = '<# cells here>';
            app.CalciumZDepthSliderLabel = uilabel(app.UIFigure);
            app.CalciumZDepthSliderLabel.Text = 'Calcium Z Depth';
            app.CalciumZDepthSlider = uislider(app.UIFigure);
            app.CalciumZDepthSlider.Limits = [1 32];
            app.CalciumZDepthSlider.ValueChangedFcn = createCallbackFcn(app, @CalciumZDepthSliderValueChanged, true);
            app.CalciumZDepthSlider.ValueChangingFcn = createCallbackFcn(app, @CalciumZDepthSliderValueChanging, true);
            app.CalciumZDepthSlider.MinorTicks = [];
            app.CalciumZDepthSlider.Value = 16;
            app.MulticolorZDepthSliderLabel = uilabel(app.UIFigure);
            app.MulticolorZDepthSliderLabel.Text = 'Multicolor Z Depth';
            app.MulticolorZDepthSlider = uislider(app.UIFigure);
            app.MulticolorZDepthSlider.Limits = [1 32];
            app.MulticolorZDepthSlider.ValueChangedFcn = createCallbackFcn(app, @MulticolorZDepthSliderValueChanged, true);
            app.MulticolorZDepthSlider.ValueChangingFcn = createCallbackFcn(app, @MulticolorZDepthSliderValueChanging, true);
            app.MulticolorZDepthSlider.MinorTicks = [];
            app.MulticolorZDepthSlider.Value = 16;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assign_calcium_to_multicolor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end