classdef check_assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes1               matlab.ui.control.UIAxes
        TitleLabel            matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        NeuronIDSpinnerLabel  matlab.ui.control.Label
        NeuronIDSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        fixed_volume
        adjusted_volume
        
        fixed_max_proj
        adjusted_max_proj
        
        fixed_points
        adjusted_points
        
        assignments
    end
    
    methods (Access = private)
        function plot_slice(app)
            neuron_ID = app.NeuronIDSpinner.Value;
            
            imagesc(app.UIAxes1, app.fixed_max_proj);
            hold(app.UIAxes1, 'on');
            scatter(app.UIAxes1, app.fixed_points(neuron_ID, 1), app.fixed_points(neuron_ID, 2))
            hold(app.UIAxes1, 'off');
            colormap(app.UIAxes1, gray);
            axis(app.UIAxes1, 'equal')
            
            imagesc(app.UIAxes2, app.adjusted_max_proj);
            if app.assignments(neuron_ID) ~= 0
                hold(app.UIAxes2, 'on');
                scatter(app.UIAxes2, app.adjusted_points(app.assignments(neuron_ID), 1), app.adjusted_points(app.assignments(neuron_ID), 2))
                hold(app.UIAxes2, 'off');
            end
            colormap(app.UIAxes2, gray);
            axis(app.UIAxes2, 'equal')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            multicolor_folder = '/home/mcreamer/Documents/data_sets/neuropal/creamer/20200130/multicolorworm_20200130_145049';
    
            % calcium
%             calcium_folder = uigetdir([], 'select the calcium imaging folder');
            calcium_folder = '/home/mcreamer/Documents/data_sets/panneuronal/BrainScanner20200130_145049';
    
            %% load assignment data
            alignment = load('/home/mcreamer/Documents/data_sets/neuropal/creamer/20200130/multicolorworm_20200130_145049/neuropal_data.csv');
            
            app.fixed_points = alignment.fixed_initial / 0.42;
            [~, sort_ids] = sort(app.fixed_points(:, 2) , 1);
            app.fixed_points = app.fixed_points(sort_ids, :);
            app.adjusted_points = alignment.adjusted_initial / 0.42;
            app.assignments = alignment.new_assignments;
            app.assignments = app.assignments(sort_ids, :);
            
            app.NeuronIDSpinner.Limits = [1, length(app.assignments)];
            
            %% load the fixed data
            fixed_folder = '/home/mcreamer/Documents/data_sets/panneuronal/BrainScanner20200130_145049/calcium_to_neuropal_alignment.mat';
        
            fixed_paths = dir(fullfile(fixed_folder, 'chunk_*.mat'));
            num_chunks = length(fixed_paths);
        
            fixed_in = cell(num_chunks, 1);
        
            for cc = 1:num_chunks
                fixed_in{cc} = load(fullfile(fixed_folder, fixed_paths(cc).name));
                fixed_in{cc} = fixed_in{cc}.red(:, :, 1:32);
            end
        
            fixed_image = fixed_in{1};
        
            for dd = 2:num_chunks
                fixed_image = fixed_image + fixed_in{dd};
            end
        
            app.fixed_volume = fixed_image / num_chunks;
            
            %% load the data to align
%             align_folder = '/home/mcreamer/Documents/fake_aml/20200130/last_2_sec';
%         
%             align_paths = dir(fullfile(align_folder, 'chunk_*.mat'));
%             num_chunks = length(align_paths);
%         
%             align_in = cell(num_chunks, 1);
%         
%             for cc = 1:num_chunks
%                 align_in{cc} = load(fullfile(align_folder, align_paths(cc).name));
%                 align_in{cc} = align_in{cc}.red(:, :, 1:32);
%             end
%         
%             align_image = align_in{1};
%         
%             for dd = 2:num_chunks
%                 align_image = align_image + align_in{dd};
%             end
%         
%             app.adjusted_volume = align_image / num_chunks;

            align_folder = '/home/mcreamer/Documents/data_sets/neuropal/creamer/20200130/multicolorworm_20200130_145049';
            aml = load(fullfile(align_folder, 'neuropal_data_mixed.aml'), '-mat');
            app.adjusted_volume = permute(aml.data(:, :, :, 4), [2, 1, 3]);
            
            app.fixed_max_proj = max(app.fixed_volume, [], 3);
            app.adjusted_max_proj = max(app.adjusted_volume, [], 3);
            
            app.plot_slice();
        end

        % Value changed function: NeuronIDSpinner
        function NeuronIDSpinnerValueChanged(app, event)
            app.plot_slice();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.NeuronIDSpinnerLabel = uilabel(app.UIFigure);
            app.NeuronIDSpinnerLabel.Text = 'Neuron ID';
            app.NeuronIDSpinner = uispinner(app.UIFigure);
            app.NeuronIDSpinner.Limits = [1 Inf];
            app.NeuronIDSpinner.ValueChangedFcn = createCallbackFcn(app, @NeuronIDSpinnerValueChanged, true);
            app.NeuronIDSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end